classdef Eq_Dif_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure                   % UI Figure
        LabelNumericEditField   matlab.ui.control.Label            % Massa ...
        nummassa                matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField2  matlab.ui.control.Label            % Aceler...
        numg                    matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField3  matlab.ui.control.Label            % Massa ...
        numrho                  matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField4  matlab.ui.control.Label            % Área d...
        nums                    matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField5  matlab.ui.control.Label            % Coefic...
        numcl                   matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField6  matlab.ui.control.Label            % Coefic...
        numcd                   matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField7  matlab.ui.control.Label            % Coefic...
        nummiu                  matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField8  matlab.ui.control.Label            % a:
        numa                    matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField9  matlab.ui.control.Label            % b:
        numb                    matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField10 matlab.ui.control.Label            % c:
        numc                    matlab.ui.control.NumericEditField % [-Inf ...
        Label                   matlab.ui.control.Label            % Coefic...
        Label2                  matlab.ui.control.Label            % m*a(t)...
        Label3                  matlab.ui.control.Label            % (=) m*...
        Button                  matlab.ui.control.Button           % Run
        LabelSwitch             matlab.ui.control.Label            % Manter...
        Switch                  matlab.ui.control.Switch           % Off, On
        Label4                  matlab.ui.control.Label            % Gáfico...
        Button2                 matlab.ui.control.Button           % X
        LabelTextArea           matlab.ui.control.Label            % Tempo ...
        TextArea                matlab.ui.control.TextArea        
        Label5                  matlab.ui.control.Label            % Veloci...
        TextArea2               matlab.ui.control.TextArea        
    end


    properties (Access = private)
        counter = 1;                    % contador para as cores dos graficos 
        T = 0;                          % Tempo ate a descolagem
        V = 0;                          % Velocidade a descolagem
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button button pushed function
        function ButtonButtonPushed(app)
            m = app.nummassa.Value;
            g = app.numg.Value;
            rho = app.numrho.Value;
            S = app.nums.Value;
            cl = app.numcl.Value;
            cd = app.numcd.Value;
            miu = app.nummiu.Value;
            a = app.numa.Value;
            b = app.numb.Value;
            c = app.numc.Value;
            
            if strcmp(app.Switch.Value,'On')                  %fazer hold dos grafico quando se clica no switch
                hold = 1;
            elseif strcmp(app.Switch.Value,'Off')
                hold = 0;
            end    
            
            [app.T, app.V] = Eq_Dif_func(m, g, rho, S, cl, cd, miu, a, b, c, hold, app.counter);              %correr a funcao
            
            app.TextArea.Value = num2str(app.T);              %Display do tempo ate a descolagem
            app.TextArea2.Value = num2str(app.V);             %Display da velocidade a descolagem
            
            app.counter = app.counter + 1;                    % contador para as cores dos graficos (cor seguinte)
            
            
        end

        % UIFigure close request function
        function UIFigureCloseRequest(app)
            
        end

        % Button2 button pushed function
        function FECHARButtonPushed(app)
            closereq                                     %butao para fechar a janela
        end

        % Switch value changed function
        function SwitchValueChanged(app)
            if strcmp(app.Switch.Value,'On')              %resolve o bug com o switch
                ButtonButtonPushed(app);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest);
            setAutoResize(app, app.UIFigure, true)
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Massa (m) [Kg]';
            app.nummassa = uieditfield(app.UIFigure, 'numeric');
            app.nummassa.ValueDisplayFormat = '%11.10g';
            app.LabelNumericEditField2 = uilabel(app.UIFigure);
            app.LabelNumericEditField2.Text = 'Aceleração gravítica (g) [m/s^2]:';
            app.numg = uieditfield(app.UIFigure, 'numeric');
            app.numg.ValueDisplayFormat = '%11.10g';
            app.numg.Value = 9.81;
            app.LabelNumericEditField3 = uilabel(app.UIFigure);
            app.LabelNumericEditField3.Text = 'Massa específica (ρ) [Kg/m^3]:';
            app.numrho = uieditfield(app.UIFigure, 'numeric');
            app.numrho.ValueDisplayFormat = '%11.10g';
            app.numrho.Value = 1.2922;
            app.LabelNumericEditField4 = uilabel(app.UIFigure);
            app.LabelNumericEditField4.Text = 'Área da superfÍcie da asa (S) [m2]:';
            app.nums = uieditfield(app.UIFigure, 'numeric');
            app.nums.ValueDisplayFormat = '%11.10g';
            app.LabelNumericEditField5 = uilabel(app.UIFigure);
            app.LabelNumericEditField5.Text = 'Coeficiente de Lift (CL):';
            app.numcl = uieditfield(app.UIFigure, 'numeric');
            app.numcl.ValueDisplayFormat = '%11.10g';
            app.LabelNumericEditField6 = uilabel(app.UIFigure);
            app.LabelNumericEditField6.Text = 'Coeficiente de Drag (Cd):';
            app.numcd = uieditfield(app.UIFigure, 'numeric');
            app.numcd.ValueDisplayFormat = '%11.10g';
            app.LabelNumericEditField7 = uilabel(app.UIFigure);
            app.LabelNumericEditField7.Text = 'Coeficiente de atrito estático (μ):';
            app.nummiu = uieditfield(app.UIFigure, 'numeric');
            app.nummiu.ValueDisplayFormat = '%11.10g';
            app.LabelNumericEditField8 = uilabel(app.UIFigure);
            app.LabelNumericEditField8.Text = 'a:';
            app.numa = uieditfield(app.UIFigure, 'numeric');
            app.numa.ValueDisplayFormat = '%11.10g';
            app.LabelNumericEditField9 = uilabel(app.UIFigure);
            app.LabelNumericEditField9.Text = 'b:';
            app.numb = uieditfield(app.UIFigure, 'numeric');
            app.numb.ValueDisplayFormat = '%11.10g';
            app.LabelNumericEditField10 = uilabel(app.UIFigure);
            app.LabelNumericEditField10.Text = 'c:';
            app.numc = uieditfield(app.UIFigure, 'numeric');
            app.numc.ValueDisplayFormat = '%11.10g';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Coeficientes de regressão:';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'm*a(t) = T(v(t)) - D(v(t)) - A(v(t)) ';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = '(=) m*a(t) = (a*v(t)^2 + b*v(t) + c) - 1/2*Cd*ρ*S*v(t)^2 - μ*(m*g - 1/2*CL*ρ*S*v(t)^2)';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonButtonPushed);
            app.Button.Text = 'Run';
            app.LabelSwitch = uilabel(app.UIFigure);
            app.LabelSwitch.Text = 'Manter gráficos anteriores';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged);
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'Gáficos de v(t), x(t) e v(x(t))';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @FECHARButtonPushed);
            app.Button2.Text = 'X';
            app.LabelTextArea = uilabel(app.UIFigure);
            app.LabelTextArea.Text = 'Tempo até à descolagem [s]:';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.Label5 = uilabel(app.UIFigure);
            app.Label5.Text = 'Velocidade à descolagem [m/s]:';
            app.TextArea2 = uitextarea(app.UIFigure);
            app.TextArea2.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Eq_Dif_App()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end